classdef SimulationV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        Button                 matlab.ui.control.Button
        Button2                matlab.ui.control.Button
        LabelEditField         matlab.ui.control.Label
        NewPath                matlab.ui.control.EditField
        Label                  matlab.ui.control.Label
        LabelSlider            matlab.ui.control.Label
        IterationSlider        matlab.ui.control.Slider
        LabelNumericEditField  matlab.ui.control.Label
        IterationEditText      matlab.ui.control.NumericEditField
        StateButton            matlab.ui.control.StateButton
        ExcelExportButton      matlab.ui.control.Button
        iterateEditFieldLabel  matlab.ui.control.Label
        iterateEditField       matlab.ui.control.NumericEditField
        Tab2                   matlab.ui.container.Tab
        Label2                 matlab.ui.control.Label
        Button3                matlab.ui.control.Button
        LabelEditField2        matlab.ui.control.Label
        PuO2ET                 matlab.ui.control.EditField
        Label6                 matlab.ui.control.Label
        UO2ET                  matlab.ui.control.EditField
        Label7                 matlab.ui.control.Label
        La2O3ET                matlab.ui.control.EditField
        Button12               matlab.ui.control.Button
        Button13               matlab.ui.control.Button
        Button16               matlab.ui.control.Button
        Label21                matlab.ui.control.Label
        Cs2OET                 matlab.ui.control.EditField
        Button17               matlab.ui.control.Button
        Label22                matlab.ui.control.Label
        SrOET                  matlab.ui.control.EditField
        Button23               matlab.ui.control.Button
        Tab3                   matlab.ui.container.Tab
        Label3                 matlab.ui.control.Label
        Button14               matlab.ui.control.Button
        Label15                matlab.ui.control.Label
        LiClET                 matlab.ui.control.EditField
        Label17                matlab.ui.control.Label
        Li2OL                  matlab.ui.control.EditField
        Label39                matlab.ui.control.Label
        CsClL                  matlab.ui.control.EditField
        Label40                matlab.ui.control.Label
        SrCl2L                 matlab.ui.control.EditField
        Label41                matlab.ui.control.Label
        LiClL                  matlab.ui.control.EditField
        Tab4                   matlab.ui.container.Tab
        Label5                 matlab.ui.control.Label
        Label30                matlab.ui.control.Label
        PuO2L                  matlab.ui.control.EditField
        Label31                matlab.ui.control.Label
        UO2L                   matlab.ui.control.EditField
        Label32                matlab.ui.control.Label
        La2O3L                 matlab.ui.control.EditField
        Label33                matlab.ui.control.Label
        Cs2OL                  matlab.ui.control.EditField
        Label34                matlab.ui.control.Label
        SrOL                   matlab.ui.control.EditField
        Label35                matlab.ui.control.Label
        UL                     matlab.ui.control.EditField
        Label36                matlab.ui.control.Label
        O2L                    matlab.ui.control.EditField
        Label37                matlab.ui.control.Label
        PuL                    matlab.ui.control.EditField
        Label38                matlab.ui.control.Label
        LaL                    matlab.ui.control.EditField
        Tab5                   matlab.ui.container.Tab
        Button18               matlab.ui.control.Button
        Label23                matlab.ui.control.Label
        rxn1ET                 matlab.ui.control.EditField
        Button19               matlab.ui.control.Button
        Label24                matlab.ui.control.Label
        rxn2ET                 matlab.ui.control.EditField
        Button20               matlab.ui.control.Button
        Label25                matlab.ui.control.Label
        rxn3ET                 matlab.ui.control.EditField
        Button21               matlab.ui.control.Button
        Label26                matlab.ui.control.Label
        rxn4ET                 matlab.ui.control.EditField
        Button22               matlab.ui.control.Button
        Label27                matlab.ui.control.Label
        rxn5ET                 matlab.ui.control.EditField
        Label28                matlab.ui.control.Label
        Label29                matlab.ui.control.Label
        Button25               matlab.ui.control.Button
        UserInputTab           matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        ProductsDropDownLabel  matlab.ui.control.Label
        ProductsDropDown       matlab.ui.control.DropDown
        ResetButton            matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        AddPlotButton          matlab.ui.control.Button
        SaltDropDownLabel      matlab.ui.control.Label
        SaltDropDown           matlab.ui.control.DropDown
        Label4                 matlab.ui.control.Label
        LabelTextArea          matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
    end


    properties (Access = private)
        r; %reaction object
        re;%reactant object
        s; %salty object
        p; %product object
    end

    methods (Access = private)
    
        function results = autoupdateProduct(app, index)
            app.UO2L.Value = num2str(app.p.r.UO2(index));
            app.UL.Value = num2str(app.p.U(index));
            app.O2L.Value = num2str(app.p.O2(index));    
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Value = '';
            
        end

        % Value changed function: IterationSlider
        function setGauge(app, event)
            app.Sauge.Value = app.IterationSlider.Value;
            
        end

        % Value changed function: StateButton
        function newWindow(app, event)
            value = app.StateButton.Value;
            t = 1:20;
            a = t * 2;
            v = plot(t,a);
            %             fig = uifigure('boop',v)
        end

        % Value changed function: IterationSlider
        function IterationSliderValueChanged(app, event)
           x = app.IterationSlider.Value;
           x = round(x);
           app.IterationEditText.Value = x;


        end

        % Value changed function: IterationEditText
        function IterationEditTextValueChanged(app, event)
            
            app.IterationSlider.Value = app.IterationEditText.Value;
            %             uiopen(filename)
        end

        % Button pushed function: Button2
        function ChoosePath(app, event)
            %IF permission denied TRY run matlab as admin
            app.r = Reaction(app.NewPath.Value); %create reaction object 
            app.re = Reactant(app.NewPath.Value); %create reactant object
            app.s = Salt(app.NewPath.Value);
            app.p = Product(app.NewPath.Value);
            app.TextArea.Value = 'Project Created';
        end

        % Value changed function: StateButton
        function Computation(app, event)
            %%
            %computation change
            index = app.r.batchCycle; %needed for auto updated function     
            iterations = (app.iterateEditField.Value);  
            
            for(i = 1 : iterations)
                app.p = elementaryReaction(app.r,app.re,app.s,app.p)
                app.r = batchCycleIterate(app.r); %changes master array size
            end
            %%
            %app visual updates
            app.TextArea.Value = num2str(app.r.batchCycle); %updates status window app.r.getBatchCycle
            app.IterationSlider.Value = app.r.getBatchCycle; %updates iteration window
            app.IterationEditText.Value = app.r.getBatchCycle;%updates iteration window
            %update product window
            autoupdateProduct(app, index);
            %             app.O2L.Value = num2str(app.p.O2(index));
            %             app.Cs2OL.Value = num2str(app.p.r.Cs2O(index));   
            %             app.LiClL.Value = num2str(app.s.LiCl(index));
            %             app.CsClL.Value = num2str(app.s.CsCl(index));
            %             app.Li2OL.Value = num2str(app.s.Li2O(index));
            
               
            %%
            rr = app.r;%more efficient than creating data over?
            rere = app.re;
            ss = app.s;
            pp = app.p;
            sData = strcat(rr.directory, '/test.mat')
            save( sData, 'rr', 'rere', 'ss', 'pp');
        end

        % Button pushed function: Button3
        function setterPuO2(app, event)
            app.re.PuO2(app.r.getBatchCycle) = str2double(app.PuO2ET.Value); 
            app.TextArea.Value = 'PuO2 updated';
        end

        % Button pushed function: Button12
        function setterUO2(app, event)
            app.re.UO2(app.r.getBatchCycle) = str2double(app.UO2ET.Value); 
            app.TextArea.Value = 'UO2 updated';
        end

        % Button pushed function: Button13
        function La2O3(app, event)
            app.re.La2O3(app.r.getBatchCycle) = str2double(app.La2O3ET.Value); 
            app.TextArea.Value = 'La2O3 updated';
        end

        % Button pushed function: Button16
        function setterCs02(app, event)
            app.re.Cs2O(app.r.getBatchCycle) = str2double(app.Cs2OET.Value); 
            app.TextArea.Value = 'CsO2 updated';
        end

        % Button pushed function: Button17
        function setterSrO(app, event)
            app.re.SrO(app.r.getBatchCycle) = str2double(app.SrOET.Value); 
            app.TextArea.Value = 'SrO updated';
        end

        % Button pushed function: Button23
        function updateAllReactant(app, event)
            app.re.La2O3(app.r.getBatchCycle) = str2double(app.La2O3ET.Value);
            app.re.UO2(app.r.getBatchCycle) = str2double(app.UO2ET.Value); 
            app.re.PuO2(app.r.getBatchCycle) = str2double(app.PuO2ET.Value);
            app.re.Cs2O(app.r.getBatchCycle) = str2double(app.Cs2OET.Value);
            app.re.SrO(app.r.getBatchCycle) = str2double(app.SrOET.Value); 
            app.TextArea.Value = 'Reactants Updated';
        end

        % Button pushed function: Button14
        function setterLiCl(app, event)
            app.s.LiCl(app.r.getBatchCycle) = str2double(app.LiClET.Value); 
            app.TextArea.Value = 'LiCl updated';
        end

        % Button pushed function: Button15
        function setterLi2O(app, event)
            app.s.Li2O(app.r.getBatchCycle) = str2double(app.Li2OL.Value); 
            app.TextArea.Value = 'Li2O updated';
        end

        % Button pushed function: Button24
        function updateAllSalt(app, event)
            app.s.LiCl(app.r.getBatchCycle) = str2double(app.LiClET.Value);
            app.s.Li2O(app.r.getBatchCycle) = str2double(app.Li2OL.Value);
            app.TextArea.Value = 'Salts Updated';
        end

        % Button pushed function: Button18
        function setterRxn1(app, event)
            app.r.rxn1(app.r.getBatchCycle) = str2double(app.rxn1ET.Value); 
            app.TextArea.Value = 'Coefficient Updated';
        end

        % Button pushed function: Button19
        function setterRxn2(app, event)
            app.r.rxn2(app.r.getBatchCycle) = str2double(app.rxn2ET.Value); 
            app.TextArea.Value = 'Coefficient Updated';
        end

        % Button pushed function: Button20
        function setterRxn3(app, event)
            app.r.rxn3(app.r.getBatchCycle) = str2double(app.rxn3ET.Value); 
            app.TextArea.Value = 'Coefficient Updated';
        end

        % Button pushed function: Button21
        function setterRxn4(app, event)
            app.r.rxn4(app.r.getBatchCycle) = str2double(app.rxn4ET.Value); 
            app.TextArea.Value = 'Coefficient Updated';
        end

        % Button pushed function: Button22
        function setterRxn5(app, event)
            app.r.rxn5(app.r.getBatchCycle) = str2double(app.rxn5ET.Value); 
            app.TextArea.Value = 'Coefficient Updated';
        end

        % Button pushed function: Button25
        function updateAllCoef(app, event)
            app.r.rxn1(app.r.getBatchCycle) = str2double(app.rxn1ET.Value);
            app.r.rxn2(app.r.getBatchCycle) = str2double(app.rxn2ET.Value); 
            app.r.rxn3(app.r.getBatchCycle) = str2double(app.rxn3ET.Value);
            app.r.rxn4(app.r.getBatchCycle) = str2double(app.rxn4ET.Value);
            app.r.rxn5(app.r.getBatchCycle) = str2double(app.rxn5ET.Value); 
            app.TextArea.Value = 'All Coefficients Updated';
        end

        % Button pushed function: Button
        function selectPath(app, event)
            folder_name = uigetdir;
            sTemp = strcat(folder_name,'/test.mat');
            tempData = load(sTemp);
            app.r =  tempData.rr; %create reaction object 
            app.re = tempData.rere; %create reactant object
            app.s = tempData.ss; %create salty object
            app.p = tempData.pp; %create product object
            app.TextArea.Value = 'Project Loaded';
        end

        % Callback function
        function UpdateDoesntworkButtonPushed(app, event)
            %             index = app.r.batchCycle;
            %             autoupdateProduct(app, index); 
              iterations = (app.iterateEditField.Value);  
              app.TextArea.Value = num2str(iterations); %updates status window app.r.getBatchCycle app.r.batchCycle
        end

        % Button pushed function: ExcelExportButton
        function ExcelExportButtonPushed(app, event)
            %%formating
            %
            filename = 'excels.xlsx';
            A = {'filename'}
            xlswrite(filename,A,'C1:C1')
            
            %%
            index = app.r.batchCycle %needed for auto updated function    
            index = index -1;
            Iterations = {1};
            for i = 1:index
                Iterations{1,i} = num2str(i)
            end
            Iterations = Iterations';
            
            U = app.p.U;
            U = U';
            O2 = app.p.O2;
            O2 = O2';
            UO2 = app.p.r.UO2
            UO2 = UO2(1,1:index)
            UO2 = UO2';
            %%cocatenating array, because they all have to be the same size to export to excel
            
            T = table(Iterations,U,O2,UO2);
            writetable(T,filename,'Sheet',1,'Range','C3')
            
            app.TextArea.Value = 'exported excel file sucessful'; %updates status window
        end

        % Button pushed function: AddPlotButton
        function AddPlotButtonPushed(app, event)
                        
            index = app.r.batchCycle;
            index = index - 1;
            x = 1:(index);          
            n = app.ProductsDropDown.Value %gets string value and will now id it
            
            switch n
                case 'U'
                    y = app.p.U;
                case 'O2'
                   y = app.p.O2;
                case 'UO2'
                   
                   y = app.p.r.UO2;
                   y = y(1,1:index); 
            end
            
            
              hold on
            %             x = 1:10;
            %             y = randi([1 10],1,10);
            %             app.TextArea.Value = app.ProductsDropDown.Value;
            x
            y
            scatter(app.UIAxes, x,y);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Log-In';
            app.Tab.Units = 'pixels';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @selectPath, true);
            app.Button.Text = 'Select Path';
            app.Button2 = uibutton(app.Tab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @ChoosePath, true);
            app.Button2.Text = 'Create';
            app.LabelEditField = uilabel(app.Tab);
            app.LabelEditField.Text = 'New Project Name';
            app.NewPath = uieditfield(app.Tab, 'text');
            app.NewPath.Value = 'type path';
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'Upload Project';
            app.LabelSlider = uilabel(app.Tab);
            app.LabelSlider.Text = 'Iteration History';
            app.IterationSlider = uislider(app.Tab);
            app.IterationSlider.ValueChangedFcn = createCallbackFcn(app, @IterationSliderValueChanged, true);
            app.LabelNumericEditField = uilabel(app.Tab);
            app.LabelNumericEditField.Text = 'Iteration History';
            app.IterationEditText = uieditfield(app.Tab, 'numeric');
            app.IterationEditText.ValueChangedFcn = createCallbackFcn(app, @IterationEditTextValueChanged, true);
            app.StateButton = uibutton(app.Tab, 'state');
            app.StateButton.ValueChangedFcn = createCallbackFcn(app, @Computation, true);
            app.StateButton.Text = 'Compute';
            app.ExcelExportButton = uibutton(app.Tab, 'push');
            app.ExcelExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelExportButtonPushed, true);
            app.ExcelExportButton.Text = 'Excel Export';
            app.iterateEditFieldLabel = uilabel(app.Tab);
            app.iterateEditFieldLabel.Text = 'iterate';
            app.iterateEditField = uieditfield(app.Tab, 'numeric');
            app.iterateEditField.Value = 1;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Reactant';
            app.Tab2.Units = 'pixels';
            app.Label2 = uilabel(app.Tab2);
            app.Label2.Text = 'Initial Reactant';
            app.Button3 = uibutton(app.Tab2, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @setterPuO2, true);
            app.Button3.Text = 'Update';
            app.LabelEditField2 = uilabel(app.Tab2);
            app.LabelEditField2.Text = 'Pu02';
            app.PuO2ET = uieditfield(app.Tab2, 'text');
            app.Label6 = uilabel(app.Tab2);
            app.Label6.Text = 'UO2';
            app.UO2ET = uieditfield(app.Tab2, 'text');
            app.Label7 = uilabel(app.Tab2);
            app.Label7.Text = 'La2O3';
            app.La2O3ET = uieditfield(app.Tab2, 'text');
            app.Button12 = uibutton(app.Tab2, 'push');
            app.Button12.ButtonPushedFcn = createCallbackFcn(app, @setterUO2, true);
            app.Button12.Text = 'Update';
            app.Button13 = uibutton(app.Tab2, 'push');
            app.Button13.ButtonPushedFcn = createCallbackFcn(app, @La2O3, true);
            app.Button13.Text = 'Update';
            app.Button16 = uibutton(app.Tab2, 'push');
            app.Button16.ButtonPushedFcn = createCallbackFcn(app, @setterCs02, true);
            app.Button16.Text = 'Update';
            app.Label21 = uilabel(app.Tab2);
            app.Label21.Text = 'Cs2O';
            app.Cs2OET = uieditfield(app.Tab2, 'text');
            app.Button17 = uibutton(app.Tab2, 'push');
            app.Button17.ButtonPushedFcn = createCallbackFcn(app, @setterSrO, true);
            app.Button17.Text = 'Update';
            app.Label22 = uilabel(app.Tab2);
            app.Label22.Text = 'SrO';
            app.SrOET = uieditfield(app.Tab2, 'text');
            app.Button23 = uibutton(app.Tab2, 'push');
            app.Button23.ButtonPushedFcn = createCallbackFcn(app, @updateAllReactant, true);
            app.Button23.Text = 'Update All';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Salt';
            app.Tab3.Units = 'pixels';
            app.Label3 = uilabel(app.Tab3);
            app.Label3.Text = 'Current Salt';
            app.Button14 = uibutton(app.Tab3, 'push');
            app.Button14.ButtonPushedFcn = createCallbackFcn(app, @setterLiCl, true);
            app.Button14.Text = 'Update';
            app.Label15 = uilabel(app.Tab3);
            app.Label15.Text = 'LiCl';
            app.LiClET = uieditfield(app.Tab3, 'text');
            app.Label17 = uilabel(app.Tab3);
            app.Label17.Text = 'Li2O';
            app.Li2OL = uieditfield(app.Tab3, 'text');
            app.Label39 = uilabel(app.Tab3);
            app.Label39.Text = 'CsCl';
            app.CsClL = uieditfield(app.Tab3, 'text');
            app.Label40 = uilabel(app.Tab3);
            app.Label40.Text = 'SrCl2';
            app.SrCl2L = uieditfield(app.Tab3, 'text');
            app.Label41 = uilabel(app.Tab3);
            app.Label41.Text = 'LiCl';
            app.LiClL = uieditfield(app.Tab3, 'text');
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Product';
            app.Tab4.Units = 'pixels';
            app.Label5 = uilabel(app.Tab4);
            app.Label5.Text = 'Product';
            app.Label30 = uilabel(app.Tab4);
            app.Label30.Text = 'Pu02';
            app.PuO2L = uieditfield(app.Tab4, 'text');
            app.Label31 = uilabel(app.Tab4);
            app.Label31.Text = 'UO2';
            app.UO2L = uieditfield(app.Tab4, 'text');
            app.Label32 = uilabel(app.Tab4);
            app.Label32.Text = 'La2O3';
            app.La2O3L = uieditfield(app.Tab4, 'text');
            app.Label33 = uilabel(app.Tab4);
            app.Label33.Text = 'Cs2O';
            app.Cs2OL = uieditfield(app.Tab4, 'text');
            app.Label34 = uilabel(app.Tab4);
            app.Label34.Text = 'SrO';
            app.SrOL = uieditfield(app.Tab4, 'text');
            app.Label35 = uilabel(app.Tab4);
            app.Label35.Text = 'U';
            app.UL = uieditfield(app.Tab4, 'text');
            app.Label36 = uilabel(app.Tab4);
            app.Label36.Text = 'O2';
            app.O2L = uieditfield(app.Tab4, 'text');
            app.Label37 = uilabel(app.Tab4);
            app.Label37.Text = 'Pu';
            app.PuL = uieditfield(app.Tab4, 'text');
            app.Label38 = uilabel(app.Tab4);
            app.Label38.Text = 'La';
            app.LaL = uieditfield(app.Tab4, 'text');
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Elementary Rxn';
            app.Tab5.Units = 'pixels';
            app.Button18 = uibutton(app.Tab5, 'push');
            app.Button18.ButtonPushedFcn = createCallbackFcn(app, @setterRxn1, true);
            app.Button18.Text = 'Update';
            app.Label23 = uilabel(app.Tab5);
            app.Label23.Text = 'Cs20 + 2LiCl  -> 2CsCl + Li2O';
            app.rxn1ET = uieditfield(app.Tab5, 'text');
            app.Button19 = uibutton(app.Tab5, 'push');
            app.Button19.ButtonPushedFcn = createCallbackFcn(app, @setterRxn2, true);
            app.Button19.Text = 'Update';
            app.Label24 = uilabel(app.Tab5);
            app.Label24.Text = 'SrO + 2LiCl -> SrCl2 + Li2O';
            app.rxn2ET = uieditfield(app.Tab5, 'text');
            app.Button20 = uibutton(app.Tab5, 'push');
            app.Button20.ButtonPushedFcn = createCallbackFcn(app, @setterRxn3, true);
            app.Button20.Text = 'Update';
            app.Label25 = uilabel(app.Tab5);
            app.Label25.Text = 'UO2 -> U + O2';
            app.rxn3ET = uieditfield(app.Tab5, 'text');
            app.Button21 = uibutton(app.Tab5, 'push');
            app.Button21.ButtonPushedFcn = createCallbackFcn(app, @setterRxn4, true);
            app.Button21.Text = 'Update';
            app.Label26 = uilabel(app.Tab5);
            app.Label26.Text = 'PuO2 -> Pu + O2';
            app.rxn4ET = uieditfield(app.Tab5, 'text');
            app.Button22 = uibutton(app.Tab5, 'push');
            app.Button22.ButtonPushedFcn = createCallbackFcn(app, @setterRxn5, true);
            app.Button22.Text = 'Update';
            app.Label27 = uilabel(app.Tab5);
            app.Label27.Text = 'La2O3 -> 2La + 1.5O2';
            app.rxn5ET = uieditfield(app.Tab5, 'text');
            app.Label28 = uilabel(app.Tab5);
            app.Label28.Text = 'Coefficient X';
            app.Label29 = uilabel(app.Tab5);
            app.Label29.Text = 'X';
            app.Button25 = uibutton(app.Tab5, 'push');
            app.Button25.ButtonPushedFcn = createCallbackFcn(app, @updateAllCoef, true);
            app.Button25.Text = 'Update All';
            app.UserInputTab = uitab(app.TabGroup);
            app.UserInputTab.Title = 'User Input';
            app.UserInputTab.Units = 'pixels';
            app.UIAxes = uiaxes(app.UserInputTab);
            title(app.UIAxes, 'UserInput');
            xlabel(app.UIAxes, 'Iterations');
            ylabel(app.UIAxes, 'Y');
            app.ProductsDropDownLabel = uilabel(app.UserInputTab);
            app.ProductsDropDownLabel.Text = 'Products';
            app.ProductsDropDown = uidropdown(app.UserInputTab);
            app.ProductsDropDown.Items = {'U', 'O2', 'UO2'};
            app.ProductsDropDown.Value = 'U';
            app.ResetButton = uibutton(app.UserInputTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.UserInputTab, 'push');
            app.SaveButton.Text = 'Save';
            app.AddPlotButton = uibutton(app.UserInputTab, 'push');
            app.AddPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlotButtonPushed, true);
            app.AddPlotButton.Text = 'Add Plot';
            app.SaltDropDownLabel = uilabel(app.UserInputTab);
            app.SaltDropDownLabel.Text = 'Salt';
            app.SaltDropDown = uidropdown(app.UserInputTab);
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = '';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Status:';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimulationV2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end