classdef SimulationV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        Button                    matlab.ui.control.Button
        Button2                   matlab.ui.control.Button
        LabelEditField            matlab.ui.control.Label
        NewPath                   matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        BatchHistoryLabel_4       matlab.ui.control.Label
        IterationEditText         matlab.ui.control.NumericEditField
        ExcelExportButton         matlab.ui.control.Button
        iterateEditFieldLabel     matlab.ui.control.Label
        iterateEditField          matlab.ui.control.NumericEditField
        BatchHistoryLabel_3       matlab.ui.control.Label
        IterationSlider           matlab.ui.control.Slider
        Go                        matlab.ui.control.StateButton
        SaveProgresstodoButton    matlab.ui.control.Button
        OutputDataTab             matlab.ui.container.Tab
        TabGroup3                 matlab.ui.container.TabGroup
        MacroTab                  matlab.ui.container.Tab
        ResetButton               matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        AddPlot_MButton           matlab.ui.control.Button
        MAxes                     matlab.ui.control.UIAxes
        m_LB_2Label               matlab.ui.control.Label
        M_LB                      matlab.ui.control.ListBox
        MicroTab                  matlab.ui.container.Tab
        mAxes                     matlab.ui.control.UIAxes
        Reset_mButton             matlab.ui.control.Button
        SaveButton_2              matlab.ui.control.Button
        AddPlot_mButton           matlab.ui.control.Button
        Re_LB_3Label              matlab.ui.control.Label
        m_LB                      matlab.ui.control.ListBox
        IterationEditText_7Label  matlab.ui.control.Label
        IterationEditText_7       matlab.ui.control.NumericEditField
        IterationSlider_7Label    matlab.ui.control.Label
        IterationSlider_7         matlab.ui.control.Slider
        RunBatchTab               matlab.ui.container.Tab
        Label2_2                  matlab.ui.control.Label
        StateButton_2             matlab.ui.control.StateButton
        ConstraintsListBoxLabel   matlab.ui.control.Label
        R_LB                      matlab.ui.control.ListBox
        Label_2                   matlab.ui.control.Label
        RunBatchET                matlab.ui.control.EditField
        Button3_3                 matlab.ui.control.Button
        BatchHistoryLabel_2       matlab.ui.control.Label
        IterationEditText_3       matlab.ui.control.NumericEditField
        BatchHistoryLabel         matlab.ui.control.Label
        IterationSlider_3         matlab.ui.control.Slider
        iterateEditField_2Label   matlab.ui.control.Label
        iterateEditField_2        matlab.ui.control.NumericEditField
        IndexTab                  matlab.ui.container.Tab
        TabGroup2                 matlab.ui.container.TabGroup
        ReactantTab               matlab.ui.container.Tab
        Label2_5                  matlab.ui.control.Label
        ButtonRE_Index            matlab.ui.control.Button
        SelectListBoxLabel        matlab.ui.control.Label
        Re_LB                     matlab.ui.control.ListBox
        PuO2ET_4Label             matlab.ui.control.Label
        ReET                      matlab.ui.control.EditField
        BatchHistoryLabel_5       matlab.ui.control.Label
        IterationEditText_2       matlab.ui.control.NumericEditField
        BatchHistoryLabel_6       matlab.ui.control.Label
        IterationSlider_2         matlab.ui.control.Slider
        SaltTab                   matlab.ui.container.Tab
        Label2_6                  matlab.ui.control.Label
        ButtonS_index             matlab.ui.control.Button
        SelectListBox_2Label      matlab.ui.control.Label
        S_LB                      matlab.ui.control.ListBox
        PuO2ET_5Label             matlab.ui.control.Label
        S_ET                      matlab.ui.control.EditField
        IterationEditText_4Label  matlab.ui.control.Label
        IterationEditText_4       matlab.ui.control.NumericEditField
        IterationSlider_4Label    matlab.ui.control.Label
        IterationSlider_4         matlab.ui.control.Slider
        ProductTab                matlab.ui.container.Tab
        Label2_7                  matlab.ui.control.Label
        ButtonP_index             matlab.ui.control.Button
        SelectListBox_3Label      matlab.ui.control.Label
        P_LB                      matlab.ui.control.ListBox
        PuO2ET_6Label             matlab.ui.control.Label
        P_ET                      matlab.ui.control.EditField
        IterationEditText_5Label  matlab.ui.control.Label
        IterationEditText_5       matlab.ui.control.NumericEditField
        IterationSlider_5Label    matlab.ui.control.Label
        IterationSlider_5         matlab.ui.control.Slider
        StoichTab                 matlab.ui.container.Tab
        Label2_8                  matlab.ui.control.Label
        Button3_7                 matlab.ui.control.Button
        SelectListBox_4Label      matlab.ui.control.Label
        SelectListBox_4           matlab.ui.control.ListBox
        PuO2ET_7Label             matlab.ui.control.Label
        PuO2ET_7                  matlab.ui.control.EditField
        IterationEditText_6Label  matlab.ui.control.Label
        IterationEditText_6       matlab.ui.control.NumericEditField
        IterationSlider_6Label    matlab.ui.control.Label
        IterationSlider_6         matlab.ui.control.Slider
        LabelTextArea             matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
    end

    % Author: Joshua Ong
    % Update: 5-15-17
    %
    %
    % Summary: Version 3 switches from object oriented to vectors & also explores simulink and numerical methods
    
    properties (Access = private)
        r; %reaction object
        re;%reactant object
        s; %salty object
        p; %product object
    end

    methods (Access = private)
    
        function results = autoupdateProduct(app, index)
%             app.UO2L.Value = num2str(app.p.r.UO2(index));
%             app.UL.Value = num2str(app.p.U(index));
%             app.O2L.Value = num2str(app.p.O2(index));    
            
        end
        
        %Utility function to update elements index tab
        function results = indexUpdate(app,ET,TAB)
            value = TAB.Value;
            switch value
                %reactants
                case 'PuO2'
                   app.re.PuO2(app.r.getBatchCycle) = str2double(ET.Value); 
                   app.TextArea.Value = 'PuO2 updated';
                case 'UO2'
                   app.re.UO2(app.r.getBatchCycle) = str2double(ET.Value); 
                   app.TextArea.Value = 'UO2 updated';
                case 'La2O3'
                   app.re.La2O3(app.r.getBatchCycle) = str2double(ET.Value); 
                   app.TextArea.Value = 'La2O3 updated';
                case 'Cs2O'
                   app.re.Cs2O(app.r.getBatchCycle) = str2double(ET.Value); 
                   app.TextArea.Value = 'Cs2O updated';
                case 'SrO'
                   app.re.SrO(app.r.getBatchCycle) = str2double(ET.Value); 
                   app.TextArea.Value = 'SrO updated';
                %salts & contamination
                case 'LiCl'
                    app.s.LiCl(app.r.getBatchCycle) = str2double(ET.Value); 
                    app.TextArea.Value = 'LiCl updated';
                case 'Li2O'
                    app.s.Li2O(app.r.getBatchCycle) = str2double(ET.Value); 
                    app.TextArea.Value = 'Li2O updated';
                case 'LiOH'
                    app.s.LiOH(app.r.getBatchCycle) = str2double(ET.Value); 
                    app.TextArea.Value = 'LiOH updated';
                case 'CsCl'
                    app.s.CsCl(app.r.getBatchCycle) = str2double(ET.Value); 
                    app.TextArea.Value = 'CsCl updated';
                case 'SrCl2'
                    app.s.SrCl2(app.r.getBatchCycle) = str2double(ET.Value); 
                    app.TextArea.Value = 'SrCl2 updated';
                case 'U'
                    app.p.U(app.r.getBatchCycle) = str2double(ET.Value); 
                    app.TextArea.Value = 'U updated';
                case 'O2'
                    app.p.O2(app.r.getBatchCycle) = str2double(ET.Value); 
                    app.TextArea.Value = 'O2 updated';
                case 'Pu'
                    app.p.Pu(app.r.getBatchCycle) = str2double(ET.Value); 
                    app.TextArea.Value = 'Pu updated';
                case 'La'
                    app.p.La(app.r.getBatchCycle) = str2double(ET.Value); 
                    app.TextArea.Value = 'La updated';
                otherwise
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Value = '';
            
        end

        % Value changed function: IterationSlider
        function setGauge(app, event)
            app.Sauge.Value = app.IterationSlider.Value;
            
        end

        % Callback function
        function IterationSliderValueChanged(app, event)
           x = app.IterationSlider.Value;
           x = round(x);
           app.IterationEditText.Value = x;


        end

        % Value changed function: IterationEditText
        function IterationEditTextValueChanged(app, event)
            
            app.IterationSlider.Value = app.IterationEditText.Value;
            %             uiopen(filename)
        end

        % Button pushed function: Button2
        function ChoosePath(app, event)
            %IF permission denied TRY run matlab as admin
            app.r = Reaction(app.NewPath.Value); %create reaction object 
            app.re = Reactant(app.NewPath.Value); %create reactant object
            app.s = Salt(app.NewPath.Value);
            app.p = Product(app.NewPath.Value);
            app.TextArea.Value = 'Project Created';
        end

        % Value changed function: Go
        function Computation(app, event)
            %%
            %computation change (the heart of the simulation)
            
            tic
            
            index = app.r.batchCycle; %needed for auto updated function     
            iterations = (app.iterateEditField.Value);  
            
            count = index;
            for(i = 1 : iterations)
                %app.p = elementaryReaction(app.r,app.re,app.s,app.p) %version 1.0
                [app.r,app.s,app.p] = Electrolysis(count,app.r,app.re,app.s,app.p);
                app.r = batchCycleIterate(app.r); %changes master array size
                count = count +1;
            end
            %%
            %app visual updates
            %app.TextArea.Value = num2str(app.r.batchCycle); %updates status window app.r.getBatchCycle
            app.IterationSlider.Value = app.r.getBatchCycle; %updates iteration window
            app.IterationEditText.Value = app.r.getBatchCycle;%updates iteration window
            %update product window
            autoupdateProduct(app, index);
            
               
            %%
            rr = app.r;%more efficient than creating data over?
            rere = app.re;
            ss = app.s;
            pp = app.p;
            sData = strcat(rr.directory, '/test.mat')
            save( sData, 'rr', 'rere', 'ss', 'pp');
            
            app.TextArea.Value  = strcat('iteration:',num2str(app.r.batchCycle),'time past:',num2str(toc)); 
        end

        % Callback function
        function updateAllReactant(app, event)
            app.re.La2O3(app.r.getBatchCycle) = str2double(app.La2O3ET.Value);
            app.re.UO2(app.r.getBatchCycle) = str2double(app.UO2ET.Value); 
            app.re.PuO2(app.r.getBatchCycle) = str2double(app.PuO2ET.Value);
            app.re.Cs2O(app.r.getBatchCycle) = str2double(app.Cs2OET.Value);
            app.re.SrO(app.r.getBatchCycle) = str2double(app.SrOET.Value); 
            app.TextArea.Value = 'Reactants Updated';
        end

        % Button pushed function: Button
        function selectPath(app, event)
            folder_name = uigetdir;
            sTemp = strcat(folder_name,'/test.mat');
            tempData = load(sTemp);
            app.r =  tempData.rr; %create reaction object 
            app.re = tempData.rere; %create reactant object
            app.s = tempData.ss; %create salty object
            app.p = tempData.pp; %create product object
            app.TextArea.Value = 'Project Loaded';
        end

        % Callback function
        function UpdateDoesntworkButtonPushed(app, event)
            %             index = app.r.batchCycle;
            %             autoupdateProduct(app, index); 
              iterations = (app.iterateEditField.Value);  
              app.TextArea.Value = num2str(iterations); %updates status window app.r.getBatchCycle app.r.batchCycle
        end

        % Button pushed function: ExcelExportButton
        function ExcelExportButtonPushed(app, event)
            %%formating
            %
            filename = 'excels.xlsx';
            A = {'filename'}
            xlswrite(filename,A,'C1:C1')
            
            %%
            index = app.r.batchCycle %needed for auto updated function    
            index = index -1;
            Iterations = {1};
            for i = 1:index
                Iterations{1,i} = num2str(i)
            end
            Iterations = Iterations';
            
            U = app.p.U;
            U = U';
            O2 = app.p.O2;
            O2 = O2';
            UO2 = app.p.r.UO2
            UO2 = UO2(1,1:index)
            UO2 = UO2';
            %%cocatenating array, because they all have to be the same size to export to excel
            
            T = table(Iterations,U,O2,UO2);
            writetable(T,filename,'Sheet',1,'Range','C3')
            
            app.TextArea.Value = 'exported excel file sucessful'; %updates status window
        end

        % Callback function
        function AddPlotButtonPushed(app, event)
                        
            index = app.r.batchCycle;
            index = index - 1;
            x = 1:(index);          
            n = app.ProductsDropDown.Value %gets string value and will now id it
            
            switch n
                case 'U'
                    y = app.p.U;
                case 'O2'
                   y = app.p.O2;
                case 'UO2'
                   
                   y = app.p.r.UO2;
                   y = y(1,1:index); 
            end
            
            
              hold on
            %             x = 1:10;
            %             y = randi([1 10],1,10);
            %             app.TextArea.Value = app.ProductsDropDown.Value;
            x
            y
            scatter(app.MAxes, x,y);
            
        end

        % Value changed function: R_LB
        function R_LBValueChanged(app, event)
            value = app.R_LB.Value;
            switch value
                case 'Current'
                    app.RunBatchET.Value = num2str(app.r.I);
                case 'Time'
                    app.RunBatchET.Value = num2str(app.r.T);
                case 'dT'
                    app.RunBatchET.Value = num2str(app.r.dT);
                otherwise
                    app.RunBatchET.Value = 'select a value to modify';
                    
            end
        end

        % Button pushed function: Button3_3
        function ButtonRunBatchUpdate(app, event)
            value = app.R_LB.Value;
            switch value
                case 'Current'
                    app.r.I = num2str(app.RunBatchET.Value);
                    app.TextArea.Value = 'Current updated';
                case 'Time'
                    app.r.T = num2str(app.RunBatchET.Value);
                    app.TextArea.Value = 'Time updated';
                case 'dT'
                    app.r.dT = num2str(app.RunBatchET.Value);
                    app.TextArea.Value = 'dT updated';
                otherwise
            end
        end

        % Button pushed function: ButtonRE_Index
        function ButtonRE_IndexPushed(app, event)
            indexUpdate(app,app.ReET,app.Re_LB);
        end

        % Button pushed function: ButtonS_index
        function ButtonS_indexPushed(app, event)
            indexUpdate(app,app.S_ET,app.S_LB);
        end

        % Button pushed function: ButtonP_index
        function ButtonP_indexPushed(app, event)
            indexUpdate(app,app.P_ET,app.P_LB);
        end

        % Button pushed function: AddPlot_mButton
        function AddPlot_mButtonPushed(app, event)
            index = app.r.batchCycle;
            index = index - 1;
            %x = 1:(index);          
            n = app.m_LB.Value; %gets string value and will now id it
            
            switch n
                case 'LiOH'
                   y = app.s.LiOH;
                case 'Li2O'
                   y = app.s.Li2O;
                case 'U'
                    y = app.p.U;
                case 'Pu'
                    y = app.p.Pu;
                case 'La'
                    y = app.p.La; 
                case 'OverPotential'
                    y = app.r.N;
                otherwise
            end
            
            
            hold(app.mAxes) ; %how to have more axes and yeah
            x = linspace(1,app.r.T,(app.r.T/app.r.dT) + 1);
            plot(app.mAxes, x,y(:,index));
        end

        % Button pushed function: Reset_mButton
        function Reset_mButtonPushed(app, event)
            x = [0 1];
            y = [0 1];
            plot(app.mAxes, x,y);
        end

        % Button pushed function: AddPlot_MButton
        function AddPlot_MButtonPushed(app, event)
            index = app.r.batchCycle;
            index = index - 1;
           % x = 1:(index);          
            n = app.M_LB.Value; %gets string value and will now id it
            
            switch n
                case 'LiOH'
                   y = app.s.LiOH;
                case 'Li2O'
                   y = app.s.Li2O;
                otherwise
            end
            
            
            hold(app.MAxes) ;
            %             x = 1:10;
            %             y = randi([1 10],1,10);
            %             app.TextArea.Value = app.ProductsDropDown.Value;
            lenY = size(y,1);
            lenX = size(y,2)
            x = 1:lenX;
            plot(app.MAxes, x,y(lenY,:));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Log-In';
            app.Tab.Units = 'pixels';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @selectPath, true);
            app.Button.Text = 'Select Path';
            app.Button2 = uibutton(app.Tab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @ChoosePath, true);
            app.Button2.Text = 'Create';
            app.LabelEditField = uilabel(app.Tab);
            app.LabelEditField.Text = 'New Project Name';
            app.NewPath = uieditfield(app.Tab, 'text');
            app.NewPath.Value = 'type path';
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'Upload Project';
            app.BatchHistoryLabel_4 = uilabel(app.Tab);
            app.BatchHistoryLabel_4.Text = 'Batch History';
            app.IterationEditText = uieditfield(app.Tab, 'numeric');
            app.IterationEditText.ValueChangedFcn = createCallbackFcn(app, @IterationEditTextValueChanged, true);
            app.ExcelExportButton = uibutton(app.Tab, 'push');
            app.ExcelExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelExportButtonPushed, true);
            app.ExcelExportButton.Text = 'Excel Export';
            app.iterateEditFieldLabel = uilabel(app.Tab);
            app.iterateEditFieldLabel.Text = 'iterate';
            app.iterateEditField = uieditfield(app.Tab, 'numeric');
            app.iterateEditField.Value = 1;
            app.BatchHistoryLabel_3 = uilabel(app.Tab);
            app.BatchHistoryLabel_3.Text = 'Batch History';
            app.IterationSlider = uislider(app.Tab);
            app.IterationSlider.ValueChangedFcn = createCallbackFcn(app, @setGauge, true);
            app.Go = uibutton(app.Tab, 'state');
            app.Go.ValueChangedFcn = createCallbackFcn(app, @Computation, true);
            app.Go.Text = 'Compute';
            app.SaveProgresstodoButton = uibutton(app.Tab, 'push');
            app.SaveProgresstodoButton.Text = 'Save Progress //todo';
            app.OutputDataTab = uitab(app.TabGroup);
            app.OutputDataTab.Title = 'Output Data';
            app.OutputDataTab.Units = 'pixels';
            app.TabGroup3 = uitabgroup(app.OutputDataTab);
            app.MacroTab = uitab(app.TabGroup3);
            app.MacroTab.Title = 'Macro';
            app.MacroTab.Units = 'pixels';
            app.ResetButton = uibutton(app.MacroTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.MacroTab, 'push');
            app.SaveButton.Text = 'Save';
            app.AddPlot_MButton = uibutton(app.MacroTab, 'push');
            app.AddPlot_MButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlot_MButtonPushed, true);
            app.AddPlot_MButton.Text = 'Add Plot';
            app.MAxes = uiaxes(app.MacroTab);
            title(app.MAxes, 'UserInput');
            xlabel(app.MAxes, 'Iterations');
            ylabel(app.MAxes, 'Y');
            app.m_LB_2Label = uilabel(app.MacroTab);
            app.m_LB_2Label.Text = 'Select';
            app.M_LB = uilistbox(app.MacroTab);
            app.M_LB.Items = {'LiOH', 'Li2O'};
            app.M_LB.Value = 'LiOH';
            app.MicroTab = uitab(app.TabGroup3);
            app.MicroTab.Title = 'Micro';
            app.MicroTab.Units = 'pixels';
            app.mAxes = uiaxes(app.MicroTab);
            title(app.mAxes, 'UserInput');
            xlabel(app.mAxes, 'Time');
            ylabel(app.mAxes, 'Moles');
            app.Reset_mButton = uibutton(app.MicroTab, 'push');
            app.Reset_mButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_mButtonPushed, true);
            app.Reset_mButton.Text = 'Reset';
            app.SaveButton_2 = uibutton(app.MicroTab, 'push');
            app.SaveButton_2.Text = 'Save';
            app.AddPlot_mButton = uibutton(app.MicroTab, 'push');
            app.AddPlot_mButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlot_mButtonPushed, true);
            app.AddPlot_mButton.Text = 'Add Plot';
            app.Re_LB_3Label = uilabel(app.MicroTab);
            app.Re_LB_3Label.Text = 'Select';
            app.m_LB = uilistbox(app.MicroTab);
            app.m_LB.Items = {'LiOH', 'Li2O', 'U', 'Pu', 'La', 'OverPotential'};
            app.m_LB.Value = 'LiOH';
            app.IterationEditText_7Label = uilabel(app.MicroTab);
            app.IterationEditText_7Label.Text = 'Batch History';
            app.IterationEditText_7 = uieditfield(app.MicroTab, 'numeric');
            app.IterationSlider_7Label = uilabel(app.MicroTab);
            app.IterationSlider_7Label.Text = 'Batch History';
            app.IterationSlider_7 = uislider(app.MicroTab);
            app.RunBatchTab = uitab(app.TabGroup);
            app.RunBatchTab.Title = 'Run Batch';
            app.RunBatchTab.Units = 'pixels';
            app.Label2_2 = uilabel(app.RunBatchTab);
            app.Label2_2.Text = 'Reaction Characteristics';
            app.StateButton_2 = uibutton(app.RunBatchTab, 'state');
            app.StateButton_2.Text = 'Compute';
            app.ConstraintsListBoxLabel = uilabel(app.RunBatchTab);
            app.ConstraintsListBoxLabel.Text = 'Constraints';
            app.R_LB = uilistbox(app.RunBatchTab);
            app.R_LB.Items = {'Current', 'Time', 'dT', 'Volume', 'Mass'};
            app.R_LB.ValueChangedFcn = createCallbackFcn(app, @R_LBValueChanged, true);
            app.R_LB.Value = 'Current';
            app.Label_2 = uilabel(app.RunBatchTab);
            app.Label_2.Text = '';
            app.RunBatchET = uieditfield(app.RunBatchTab, 'text');
            app.Button3_3 = uibutton(app.RunBatchTab, 'push');
            app.Button3_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonRunBatchUpdate, true);
            app.Button3_3.Text = 'Update';
            app.BatchHistoryLabel_2 = uilabel(app.RunBatchTab);
            app.BatchHistoryLabel_2.Text = 'Batch History';
            app.IterationEditText_3 = uieditfield(app.RunBatchTab, 'numeric');
            app.BatchHistoryLabel = uilabel(app.RunBatchTab);
            app.BatchHistoryLabel.Text = 'Batch History';
            app.IterationSlider_3 = uislider(app.RunBatchTab);
            app.iterateEditField_2Label = uilabel(app.RunBatchTab);
            app.iterateEditField_2Label.Text = 'iterate';
            app.iterateEditField_2 = uieditfield(app.RunBatchTab, 'numeric');
            app.iterateEditField_2.Value = 1;
            app.IndexTab = uitab(app.TabGroup);
            app.IndexTab.Title = 'Index';
            app.IndexTab.Units = 'pixels';
            app.TabGroup2 = uitabgroup(app.IndexTab);
            app.ReactantTab = uitab(app.TabGroup2);
            app.ReactantTab.Title = 'Reactant';
            app.ReactantTab.Units = 'pixels';
            app.Label2_5 = uilabel(app.ReactantTab);
            app.Label2_5.Text = 'Reaction Characteristics';
            app.ButtonRE_Index = uibutton(app.ReactantTab, 'push');
            app.ButtonRE_Index.ButtonPushedFcn = createCallbackFcn(app, @ButtonRE_IndexPushed, true);
            app.ButtonRE_Index.Text = 'Update';
            app.SelectListBoxLabel = uilabel(app.ReactantTab);
            app.SelectListBoxLabel.Text = 'Select';
            app.Re_LB = uilistbox(app.ReactantTab);
            app.Re_LB.Items = {'PuO2', 'UO2', 'La2O3', 'Cs2O', 'SrO'};
            app.Re_LB.Value = 'PuO2';
            app.PuO2ET_4Label = uilabel(app.ReactantTab);
            app.PuO2ET_4Label.Text = '';
            app.ReET = uieditfield(app.ReactantTab, 'text');
            app.BatchHistoryLabel_5 = uilabel(app.ReactantTab);
            app.BatchHistoryLabel_5.Text = 'Batch History';
            app.IterationEditText_2 = uieditfield(app.ReactantTab, 'numeric');
            app.BatchHistoryLabel_6 = uilabel(app.ReactantTab);
            app.BatchHistoryLabel_6.Text = 'Batch History';
            app.IterationSlider_2 = uislider(app.ReactantTab);
            app.SaltTab = uitab(app.TabGroup2);
            app.SaltTab.Title = 'Salt';
            app.SaltTab.Units = 'pixels';
            app.Label2_6 = uilabel(app.SaltTab);
            app.Label2_6.Text = 'Reaction Characteristics';
            app.ButtonS_index = uibutton(app.SaltTab, 'push');
            app.ButtonS_index.ButtonPushedFcn = createCallbackFcn(app, @ButtonS_indexPushed, true);
            app.ButtonS_index.Text = 'Update';
            app.SelectListBox_2Label = uilabel(app.SaltTab);
            app.SelectListBox_2Label.Text = 'Select';
            app.S_LB = uilistbox(app.SaltTab);
            app.S_LB.Items = {'LiCl', 'Li2O', 'LiOH', 'CsCl', 'SrCl2'};
            app.S_LB.Value = 'LiCl';
            app.PuO2ET_5Label = uilabel(app.SaltTab);
            app.PuO2ET_5Label.Text = '';
            app.S_ET = uieditfield(app.SaltTab, 'text');
            app.IterationEditText_4Label = uilabel(app.SaltTab);
            app.IterationEditText_4Label.Text = 'Batch History';
            app.IterationEditText_4 = uieditfield(app.SaltTab, 'numeric');
            app.IterationSlider_4Label = uilabel(app.SaltTab);
            app.IterationSlider_4Label.Text = 'Batch History';
            app.IterationSlider_4 = uislider(app.SaltTab);
            app.ProductTab = uitab(app.TabGroup2);
            app.ProductTab.Title = 'Product';
            app.ProductTab.Units = 'pixels';
            app.Label2_7 = uilabel(app.ProductTab);
            app.Label2_7.Text = 'Reaction Characteristics';
            app.ButtonP_index = uibutton(app.ProductTab, 'push');
            app.ButtonP_index.ButtonPushedFcn = createCallbackFcn(app, @ButtonP_indexPushed, true);
            app.ButtonP_index.Text = 'Update';
            app.SelectListBox_3Label = uilabel(app.ProductTab);
            app.SelectListBox_3Label.Text = 'Select';
            app.P_LB = uilistbox(app.ProductTab);
            app.P_LB.Items = {'U', 'O2', 'Pu', 'La'};
            app.P_LB.Value = 'U';
            app.PuO2ET_6Label = uilabel(app.ProductTab);
            app.PuO2ET_6Label.Text = '';
            app.P_ET = uieditfield(app.ProductTab, 'text');
            app.IterationEditText_5Label = uilabel(app.ProductTab);
            app.IterationEditText_5Label.Text = 'Batch History';
            app.IterationEditText_5 = uieditfield(app.ProductTab, 'numeric');
            app.IterationSlider_5Label = uilabel(app.ProductTab);
            app.IterationSlider_5Label.Text = 'Batch History';
            app.IterationSlider_5 = uislider(app.ProductTab);
            app.StoichTab = uitab(app.TabGroup2);
            app.StoichTab.Title = 'Stoich';
            app.StoichTab.Units = 'pixels';
            app.Label2_8 = uilabel(app.StoichTab);
            app.Label2_8.Text = 'Reaction Characteristics';
            app.Button3_7 = uibutton(app.StoichTab, 'push');
            app.Button3_7.Text = 'Update';
            app.SelectListBox_4Label = uilabel(app.StoichTab);
            app.SelectListBox_4Label.Text = 'Select';
            app.SelectListBox_4 = uilistbox(app.StoichTab);
            app.SelectListBox_4.Items = {'???'};
            app.SelectListBox_4.Value = '???';
            app.PuO2ET_7Label = uilabel(app.StoichTab);
            app.PuO2ET_7Label.Text = '';
            app.PuO2ET_7 = uieditfield(app.StoichTab, 'text');
            app.IterationEditText_6Label = uilabel(app.StoichTab);
            app.IterationEditText_6Label.Text = 'Batch History';
            app.IterationEditText_6 = uieditfield(app.StoichTab, 'numeric');
            app.IterationSlider_6Label = uilabel(app.StoichTab);
            app.IterationSlider_6Label.Text = 'Batch History';
            app.IterationSlider_6 = uislider(app.StoichTab);
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Status:';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimulationV3()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end